classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        ValuePanel     matlab.ui.container.Panel
        LampConnect    matlab.ui.control.Lamp
        Label_6        matlab.ui.control.Label
        SwitchConnect  matlab.ui.control.ToggleSwitch
        LampRejim3     matlab.ui.control.Lamp
        Label_5        matlab.ui.control.Label
        LampRejim2     matlab.ui.control.Lamp
        Label_4        matlab.ui.control.Label
        LampRejim1     matlab.ui.control.Lamp
        Label_3        matlab.ui.control.Label
        Regim          matlab.ui.container.ButtonGroup
        ButtRegim3     matlab.ui.control.RadioButton
        ButtRegim2     matlab.ui.control.RadioButton
        ButtRegim1     matlab.ui.control.RadioButton
        Knob           matlab.ui.control.Knob
        Go             matlab.ui.control.Lamp
        Label_2        matlab.ui.control.Label
        SwitchGo       matlab.ui.control.ToggleSwitch
        Gauge          matlab.ui.control.Gauge
        Tab_2          matlab.ui.container.Tab
        PlotPanel      matlab.ui.container.Panel
        GrafikLamp     matlab.ui.control.Lamp
        GrafikSwitch   matlab.ui.control.ToggleSwitch
        Label_7        matlab.ui.control.Label
        ValueGroup     matlab.ui.container.ButtonGroup
        Voltage        matlab.ui.control.RadioButton
        Current        matlab.ui.control.RadioButton
        Speed          matlab.ui.control.RadioButton
        Grafik         matlab.ui.control.UIAxes
        Tab_3          matlab.ui.container.Tab
        UITable2       matlab.ui.control.Table
    end

    
    properties (Access = private)
       %Modbus
        flag = 0; % Description
        m; % Description
        registranalog;
        regim=2;
        zapusk=1;
        %
        
        %Grafik
        x_axis = [0,0];
        speed_A_axis = [0,0];
        update_period = 1;         % How often to update plot, points
        time_window = 10;           % Time window to be shown on figure, sec
        counter = 0;
        new_values = [];
        start_time = clock;
        flaggrafik=0;
        value=1;
        %
    end
    
    methods (Access = private)
        
        function Modbus(app)
              app.m = modbus('tcpip', '192.168.1.123', 80);
              while(app.flag)
                if(app.zapusk==1)
                    app.zapusk=0;
                    write(app.m,'coils',1,(1));
                    write(app.m,'coils',app.regim,(1))
                end
                zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                zapros_disk=read(app.m,'coils',1,50);
                registrdisk=transpose(zapros_disk);
                app.registranalog=transpose(zapros_analog);
                app.UITable2.Data=([app.registranalog,registrdisk]);
                app.Gauge.Value=app.registranalog(1);
                pause(1)
              end
              write(app.m,'coils',1,(1))
              app.zapusk=1;
              clear m;

        end
        
        function GrafikData(app)
              while(app.flaggrafik)
              app.counter = app.counter + 1;
              app.new_values = app.registranalog(app.value);
              % Add new points to the plot and forget last one if necessary
              if app.x_axis(end) >= app.time_window
                 app.x_axis = [app.x_axis(2:end) etime(clock, app.start_time)];
                 app.speed_A_axis = [app.speed_A_axis(2:end) app.new_values ];
              else
                 app.x_axis = [app.x_axis etime(clock, app.start_time)];
                 app.speed_A_axis = [app.speed_A_axis app.new_values];
              end
              % Update plot
              if app.counter == app.update_period
                plot(app.Grafik,  app.x_axis,  app.speed_A_axis);
              if app.x_axis(end) >= app.time_window
                app.Grafik.XLim=[app.x_axis(1) app.x_axis(end)];
              end
              drawnow;
              app.counter = 0;
              end
              end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SwitchGo
        function SwitchGoValueChanged(app, event)
         value = app.SwitchGo.Value;
            if strcmp(value,'Вкл')
                app.Go.Color='g';
                app.flag = 1;

            else
                app.Go.Color='r';
                app.flag = 0;
            end
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value=app.Knob.Value;  
            if(app.flag==1)
                write(app.m,'holdingregs',1,floor(value),'uint16')
            end
        end

        % Selection changed function: Regim
        function RegimSelectionChanged(app, event)
            selectedButton = app.Regim.SelectedObject;
            if selectedButton==app.ButtRegim1
                if(app.flag==0)
                 app.LampRejim1.Color='g';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='r';
                 app.regim=2;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='g';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='r';
                 write(app.m,'coils',2,(1));
                 write(app.m,'coils',3,(0));
                 write(app.m,'coils',4,(0));
                end
            end
            if selectedButton==app.ButtRegim2
                if(app.flag==0)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='g';
                 app.LampRejim3.Color='r';
                 app.regim=3;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='g';
                 app.LampRejim3.Color='r';
                 write(app.m,'coils',2,(0));
                 write(app.m,'coils',3,(1));
                 write(app.m,'coils',4,(0));
                end
            end
            if selectedButton==app.ButtRegim3
                if(app.flag==0)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='g';
                 app.regim=4;
                end
                if(app.flag==1)
                 app.LampRejim1.Color='r';
                 app.LampRejim2.Color='r';
                 app.LampRejim3.Color='g';
                 write(app.m,'coils',2,(0));
                 write(app.m,'coils',3,(0));
                 write(app.m,'coils',4,(1));
                end
            end
            
        end

        % Value changed function: SwitchConnect
        function SwitchConnectValueChanged(app, event)
            value = app.SwitchConnect.Value;
            if strcmp(value,'Вкл')
                app.LampConnect.Color='g';
                app.flag = 1;
                Modbus(app)
            else
                app.LampConnect.Color='r';
            end
        end

        % Selection changed function: ValueGroup
        function ValueGroupSelectionChanged(app, event)
            selectedButton = app.ValueGroup.SelectedObject;
            if selectedButton==app.Speed
                title(app.Grafik, 'Зависимость скорости от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Скорость, об/с')
                app.value=1;
            end
            if selectedButton==app.Current
                title(app.Grafik, 'Зависимость тока от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Ток, А')
                app.value=2;
                app.x_axis = [0,0];
                app.speed_A_axis = [0,0];
                plot(app.Grafik,  app.x_axis,  app.speed_A_axis);
            end
            if selectedButton==app.Voltage
                title(app.Grafik, 'Зависимость напряжения от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Напряжение, В')
                app.value=3;
            end            
        end

        % Value changed function: GrafikSwitch
        function GrafikSwitchValueChanged(app, event)
            value = app.GrafikSwitch.Value;
            if strcmp(value,'Вкл')
                app.GrafikLamp.Color='g';
                app.flaggrafik=1;
                GrafikData(app)
            else
                app.GrafikLamp.Color='r';
                app.flaggrafik=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.Gauge = uigauge(app.ValuePanel, 'circular');
            app.Gauge.Limits = [0 1000];
            app.SwitchGo = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchGo.Items = {'Выкл', 'Вкл'};
            app.SwitchGo.ValueChangedFcn = createCallbackFcn(app, @SwitchGoValueChanged, true);
            app.SwitchGo.Value = 'Выкл';
            app.Label_2 = uilabel(app.ValuePanel);
            app.Label_2.Text = {'Запуск'; ''};
            app.Go = uilamp(app.ValuePanel);
            app.Knob = uiknob(app.ValuePanel, 'continuous');
            app.Knob.Limits = [0 1000];
            app.Knob.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Knob.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Regim = uibuttongroup(app.ValuePanel);
            app.Regim.SelectionChangedFcn = createCallbackFcn(app, @RegimSelectionChanged, true);
            app.Regim.Title = 'Выбор режима';
            app.ButtRegim1 = uiradiobutton(app.Regim);
            app.ButtRegim1.Text = 'Режим 1';
            app.ButtRegim1.Value = true;
            app.ButtRegim2 = uiradiobutton(app.Regim);
            app.ButtRegim2.Text = 'Режим 2';
            app.ButtRegim3 = uiradiobutton(app.Regim);
            app.ButtRegim3.Text = 'Режим 3';
            app.Label_3 = uilabel(app.ValuePanel);
            app.Label_3.Text = 'Режим 1';
            app.LampRejim1 = uilamp(app.ValuePanel);
            app.Label_4 = uilabel(app.ValuePanel);
            app.Label_4.Text = 'Режим 2';
            app.LampRejim2 = uilamp(app.ValuePanel);
            app.Label_5 = uilabel(app.ValuePanel);
            app.Label_5.Text = 'Режим 3';
            app.LampRejim3 = uilamp(app.ValuePanel);
            app.SwitchConnect = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchConnect.Items = {'Выкл', 'Вкл'};
            app.SwitchConnect.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectValueChanged, true);
            app.SwitchConnect.Value = 'Выкл';
            app.Label_6 = uilabel(app.ValuePanel);
            app.Label_6.Text = 'Подключение';
            app.LampConnect = uilamp(app.ValuePanel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab_2);
            app.PlotPanel.Title = 'График';
            app.Grafik = uiaxes(app.PlotPanel);
            title(app.Grafik, 'Зависимость скорости от времени')
            xlabel(app.Grafik, 'Время, с')
            ylabel(app.Grafik, 'Скорость, об/с')
            zlabel(app.Grafik, 'Z')
            app.Grafik.YLim = [0 1000];
            app.ValueGroup = uibuttongroup(app.PlotPanel);
            app.ValueGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueGroupSelectionChanged, true);
            app.ValueGroup.Title = 'Выбор величины';
            app.Speed = uiradiobutton(app.ValueGroup);
            app.Speed.Text = 'Скорость';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ValueGroup);
            app.Current.Text = 'Ток';
            app.Voltage = uiradiobutton(app.ValueGroup);
            app.Voltage.Text = 'Напряжение';
            app.Label_7 = uilabel(app.PlotPanel);
            app.Label_7.Text = 'Запуск  графика';
            app.GrafikSwitch = uiswitch(app.PlotPanel, 'toggle');
            app.GrafikSwitch.Items = {'Выкл', 'Вкл'};
            app.GrafikSwitch.ValueChangedFcn = createCallbackFcn(app, @GrafikSwitchValueChanged, true);
            app.GrafikSwitch.Value = 'Выкл';
            app.GrafikLamp = uilamp(app.PlotPanel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab_3);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end